classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AnalyzeButton                 matlab.ui.control.Button
        ThepatienthasthefollowingchanceofhavingThyroidCancerLabel  matlab.ui.control.Label
        ChanceasapercentageEditFieldLabel  matlab.ui.control.Label
        ChanceasapercentageEditField  matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        ComputeraidedmedicaldiagnosissystemLabel  matlab.ui.control.Label
        TheimagetobeanalyzedisLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
       
    end
    
    properties (Access = private)
       
    end
   
    
    methods (Access = public)
       
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Incarcare imagine si afisare
             [filename,filepath]=uigetfile('*.jpg','C:\Users\Lidia\Desktop\Proiect IRA\test\'); %Deschidere folder test
             fullname=[filepath,filename];
             ImageFile=imread(fullname); %Citire imagine
            imagesc(app.UIAxes,ImageFile); %Afisare imagine in aplicatie
            %Testare retea cu imaginea selectata
            
            ImageFile=rgb2gray(ImageFile); %Transformare in matrice 2d
            ImageFile=ImageFile(:); %Transformare in vector
            ImageFile=im2double(ImageFile); %Reconfigurare tip (in double)
            ImageFile=ImageFile/255; %Deoarece avem numere cuprinse intre 0 si 255 in array, vom imparti cu 255 toate aceste elemente pentru a lucra cu numere mai mici
            TestImage{1}=ImageFile;  %Punem imaginea intr-un cellarray pentru a putea fi data ca parametru functiei
           [Y] = myNeuralNetworkFunction(TestImage{1}); %Apelare functie
           app.ChanceasapercentageEditField.Value=Y; %Afisare rezultat
          % variabila=Y{1}*100;
          % variabila2=string(variabila);
           %app.ChanceasapercentageEditField.Value=strcat(variabila2,'%');
            %%ImageFile=im2double(ImageFile);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'thyroid-cancer-2BEH7FA.jpg';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = {'Analyze!'; ''};
            app.ThepatienthasthefollowingchanceofhavingThyroidCancerLabel = uilabel(app.UIFigure);
            app.ThepatienthasthefollowingchanceofhavingThyroidCancerLabel.Text = {'The patient has the following chance of having Thyroid Cancer:'; ''};
            app.ChanceasapercentageEditFieldLabel = uilabel(app.UIFigure);
            app.ChanceasapercentageEditFieldLabel.Text = {'Chance as a percentage:'; ''};
            app.ChanceasapercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '9938-health-care-medical-wallpaper-top-free-health-care-medical.jpg';
            app.ComputeraidedmedicaldiagnosissystemLabel = uilabel(app.UIFigure);
            app.ComputeraidedmedicaldiagnosissystemLabel.Text = 'Computer aided medical diagnosis system';
            app.TheimagetobeanalyzedisLabel = uilabel(app.UIFigure);
            app.TheimagetobeanalyzedisLabel.Text = 'The image to be analyzed is:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end